classdef HORUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GraficasScreen              matlab.ui.container.Panel
        GraficasGrid                matlab.ui.container.GridLayout
        FSLabel                     matlab.ui.control.Label
        BackGraficasHTML            matlab.ui.control.HTML
        AnalizarMedidasHTML         matlab.ui.control.HTML
        EYEL                        matlab.ui.control.Image
        EYER                        matlab.ui.control.Image
        StartBT                     matlab.ui.control.Button
        ConectedLampGrid            matlab.ui.container.GridLayout
        ConectedLamp                matlab.ui.control.Lamp
        ConectedLBL                 matlab.ui.control.Label
        VEOL                        matlab.ui.control.UIAxes
        HEOR                        matlab.ui.control.UIAxes
        HEOL                        matlab.ui.control.UIAxes
        VEOR                        matlab.ui.control.UIAxes
        AnalisisSignalsScreen       matlab.ui.container.Panel
        AnalisisSignalsGrid         matlab.ui.container.GridLayout
        ResultadosAnalisisHTML      matlab.ui.control.HTML
        VerticalRExpandedHTML       matlab.ui.control.HTML
        VerticalLExpandedHTML       matlab.ui.control.HTML
        HorizontalRExpandedHTML     matlab.ui.control.HTML
        HorizontalLExpandedHTML     matlab.ui.control.HTML
        EYEL2                       matlab.ui.control.Image
        EYER2                       matlab.ui.control.Image
        RHorizontalAxis             matlab.ui.control.UIAxes
        RVerticalAxis               matlab.ui.control.UIAxes
        LHorizontalAxis             matlab.ui.control.UIAxes
        LVerticalAxis               matlab.ui.control.UIAxes
        ExpandedViewScreen          matlab.ui.container.Panel
        ExpandedViewGrid            matlab.ui.container.GridLayout
        BackExpandedViewHTML        matlab.ui.control.HTML
        EyePanelR                   matlab.ui.container.Panel
        EYER_2                      matlab.ui.control.Image
        PlayButton                  matlab.ui.control.Button
        EyePanelL                   matlab.ui.container.Panel
        EYEL_2                      matlab.ui.control.Image
        ExpandedPlot                matlab.ui.control.UIAxes
        AnalisisScreen              matlab.ui.container.Panel
        AnalisisGrid                matlab.ui.container.GridLayout
        ObservacionesLBL            matlab.ui.control.Label
        ObservacionesNamelbl        matlab.ui.control.Label
        EventsAnalisisButton        matlab.ui.control.Button
        TypeAnalisisLabel           matlab.ui.control.Label
        AnalisisTypeDropDown        matlab.ui.control.DropDown
        ExportButton                matlab.ui.control.Button
        miradasDerechaLbl           matlab.ui.control.Label
        miradasIzquierdaLbl         matlab.ui.control.Label
        UITableResultadosDerecha    matlab.ui.control.Table
        FatigaLabel                 matlab.ui.control.Label
        PatientInfoLabel            matlab.ui.control.Label
        FATIGAname                  matlab.ui.control.Label
        DATOSDELPACIENTELabel       matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        ANLISISPACIENTELabel        matlab.ui.control.Label
        UITableResultadosIzquierda  matlab.ui.control.Table
        GoBackButtonAnalisis        matlab.ui.control.Button
        MainScreen                  matlab.ui.container.Panel
        MainGrid                    matlab.ui.container.GridLayout
        PasswordHTML                matlab.ui.control.HTML
        UserNameHTML                matlab.ui.control.HTML
        ConectionCableCheckBox      matlab.ui.control.CheckBox
        AccederHTML                 matlab.ui.control.HTML
        Hyperlink                   matlab.ui.control.Hyperlink
        SignInStateLBL              matlab.ui.control.Label
        LOGOSEPA                    matlab.ui.control.Image
        SignInLBL                   matlab.ui.control.Label
        ConectScreen                matlab.ui.container.Panel
        ConectGrid                  matlab.ui.container.GridLayout
        BackConectHTML              matlab.ui.control.HTML
        TomarMedidasHTML            matlab.ui.control.HTML
        BuscarDevicesHTML           matlab.ui.control.HTML
        LogoHorusHTML               matlab.ui.control.HTML
        DevicesListHTML             matlab.ui.control.HTML
        WifiStatusHTML              matlab.ui.control.HTML
        ShowHiddenWifisCheckBox     matlab.ui.control.CheckBox
        ChooseActionScreen          matlab.ui.container.Panel
        ChooseActionGrid            matlab.ui.container.GridLayout
        BackChooseActionHTML        matlab.ui.control.HTML
        ConectarGafasHTML           matlab.ui.control.HTML
        VerAnalisisPreviosHTML      matlab.ui.control.HTML
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        EventsAnalisisScreen        matlab.ui.container.Panel
        EventsAnalisisGrid          matlab.ui.container.GridLayout
        EventsTable                 matlab.ui.control.Table
        TotalEventsLBL              matlab.ui.control.Label
        EventsAnalisisLBL           matlab.ui.control.Label
        GoBackButtonEvents          matlab.ui.control.Button
    end


    properties (Access = private)
        usuario
        password
        WifiOff
        AvailableWifis
        DevicesWifis
        WifiInterface
        WifiTemplate
        PasswordOptions = ["zeri3333","wifi.ROS1","00000000","12345678"];
        ConnectedDevice = false;
        Device
        SHEOL
        SVEOL
        SHEOR
        SVEOR
        LineSHEOL = []
        LineSVEOL = []
        LineSHEOR = []
        LineSVEOR = []
        NoDataCount     % contador de intentos sin datos
        TimerGraficar        % timer de adquisición
        counter               % contador de muestras
        lastTime
        dtBuffer
        fs_est
        fs_fixed
        fs_calibrating = true
        fs_startTime
        FilterState
    end

    methods (Access = private)

        function ChangeWindowTo(app,screen)
            screens = [...
                app.MainScreen,...
                app.ChooseActionScreen,...
                app.ConectScreen,...
                app.GraficasScreen,...
                app.AnalisisScreen,...
                app.EventsAnalisisScreen,...
                app.AnalisisSignalsScreen,...
                app.ExpandedViewScreen
                ];
            for a = screens
                a.Visible = 'off';
            end
            screen.Visible = 'on';
        end
        function PrepareWifi(app)
            wifiName = "";
            if ispc

                % detectar adaptador wifi (incluso deshabilitado)
                cmd = ['powershell -Command "', ...
                    'Get-NetAdapter | Where-Object {$_.NdisPhysicalMedium -eq 9} | Select-Object -First 1 -ExpandProperty Name"'];

                [~,out] = system(cmd);
                wifiName = strtrim(out);

                if wifiName == ""
                    error("No se encontró adaptador WiFi");
                end
                % comprobar estado
                [~,interfaces] = system('netsh interface show interface');

                if ~contains(interfaces,"Enabled")

                    cmd = sprintf('netsh interface set interface "%s" admin=enabled',wifiName);
                    system(cmd);

                end
                app.WifiOff = 0;
                % desconectar wifi actual
                system('netsh wlan disconnect');
            else
                app.WifiInterface = wifiName;
                error("Sistema operativo no soportado")
            end
            app.WifiInterface = wifiName;
        end
        function FindAvailableWifis(app)
            app.AvailableWifis = strings(0);
            ssids = strings(0);

            if ispc
                % ----------------
                % Windows
                % ----------------;
                psCommand = sprintf([
                    '$wifi = Get-NetAdapter -Name "%s"; ' ...
                    'Disable-NetAdapter -Name $wifi.Name -Confirm:$false; ' ...
                    'Start-Sleep -Seconds 2; ' ...
                    'Enable-NetAdapter -Name $wifi.Name -Confirm:$false;'], app.WifiInterface);

                % Ejecutar desde MATLAB
                [status, out] = system(['powershell -Command "', psCommand, '"']);

                % Comprobar errores
                if status ~= 0
                    error("Error forzando reescaneo Wi-Fi:\n%s", out);
                end

                ready = false;

                for k = 1:10

                    pause(1)

                    [~,state] = system('netsh interface show interface');
                    disp(state)
                    disp(app.WifiInterface)
                    lines = splitlines(state);

                    % Buscar la línea de la interfaz WiFi
                    wifiLine = lines(contains(lines, app.WifiInterface));

                    if ~isempty(wifiLine) && contains(wifiLine,["Enabled","Habilitado"])
                        ready = true;
                        break
                    end

                end

                if ~ready
                    error("La interfaz WiFi no se activó correctamente")
                end

                timeout = 10;
                t0 = tic;

                while toc(t0) < timeout

                    [status,out] = system('netsh wlan show networks mode=bssid');

                    if status == 0
                        break
                    end

                    pause(0.5)

                end

                if status ~= 0
                    error("Error escaneando WiFi en Windows:\n%s", out)
                end
                % Cada red tiene línea "SSID 1 : NombreRed"
                lines = splitlines(out);
                ssidLines = lines(startsWith(strtrim(lines),'SSID '));
                for i = 1:length(ssidLines)
                    parts = split(ssidLines{i},':');
                    if numel(parts) >= 2
                        ssid = strtrim(parts{2});
                        if ~isempty(ssid)
                            ssids(end+1) = ssid;
                        end
                    end
                end
            else
                error("Sistema operativo no soportado")
            end

            % Limpiar duplicados y vacíos
            ssids = unique(ssids);
            app.AvailableWifis = flip(ssids(ssids ~= ""));
        end

        function ReloadListWifis(app,listWifis)
            app.DevicesListHTML.Data = {};
            app.DevicesListHTML.Data = listWifis;
        end
        function createWifiProfile(app,ssid,password)
            system("netsh wlan delete profile name=""" + ssid + """")
            app.WifiTemplate = fileread("wifi_template.xml");

            profile = strrep(app.WifiTemplate,"SSID_AQUI",ssid);
            profile = strrep(profile,"PASSWORD_AQUI",password);

            fid = fopen("wifi_temp.xml",'w');
            fprintf(fid,profile);
            fclose(fid);

            system('netsh wlan add profile filename="wifi_temp.xml" user=current');

        end

        function DataConnection(app)
            try
                if ~isempty(app.Device)
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                app.Device = tcpclient("192.168.4.1",5000);
                disp('Conectado a ESP32');
                app.ConectedLamp.Color = 'green';
                app.StartBT.Enable = 'on';

            catch
                app.Device = [];  % devuelve vacío si falla
                app.ConectedLamp.Color = 'red';
                app.StartBT.Enable = 'off';
            end
        end

        function DataConnectionCable(app)
            try
                if ~isempty(app.Device)
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                % https://es.mathworks.com/help/matlab/import_export/read-streaming-data-from-arduino.html
                % Cerramos las conexiones serialport existentes
                delete(serialportfind);
                % Guardamos los datos del serialport en "serialObj"
                app.Device = serialport("COM5",9600);
                % Definimos la propiedad Terminator para que coincida con el terminador
                % especificado en el codigo de arduino
                configureTerminator(app.Device,"CR/LF");
                % Vaciamos serialObj para limpiar datos antiguos
                flush(app.Device);
                app.ConectedLamp.Color = 'green';
                app.StartBT.Enable = 'on';
            catch
                app.Device = [];  % devuelve vacío si falla
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ningun Dispositivo Conectado');
                app.ConectedLamp.Color = 'red';
                app.StartBT.Enable = 'off';
            end

        end
        function ChangeWifiStatus (app,state)
            app.WifiStatusHTML.Data = state;
        end
        function RefreshWifis(app)
            ChangeWifiStatus(app,'desconectado');
            app.TomarMedidasHTML.Visible = 'off';
            ReloadListWifis(app,{});
            LoadingWifis = uiprogressdlg(app.UIFigure, ...
                'Title', 'Buscando Dispositivos...', 'Message', 'Porfavor espere mientras se cargan los dispositivos...', 'Indeterminate', 'on');
            drawnow
            FindAvailableWifis(app);
            if isempty(app.AvailableWifis)
                close(LoadingWifis);
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ninguna Wifi Encontrada');
            else
                %const char* ssid = "ESP32SEPA"; // NOMBRE DE LA RED A CREAR
                %const char* password = "zeri3333";   // PASSWORD DE LA RED (MINIMO 8CHAR)
                comparador = contains(app.AvailableWifis, "ROS", "IgnoreCase", true);
                app.DevicesWifis = app.AvailableWifis(comparador);
                if isempty(app.DevicesWifis)
                    uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido o busque en Redes Escondidas','Ningun Dispositivo Encontrado','Icon','warning');
                end
                ReloadListWifis(app, app.DevicesWifis);
                close(LoadingWifis);
            end
        end

        function DataAcquisition(app)
            app.FilterState = struct();
            % Limpiar líneas animadas anteriores si existen
            if ~isempty(app.LineSHEOL) && isvalid(app.LineSHEOL)
                delete(app.LineSHEOL);
                delete(app.LineSVEOL);
                delete(app.LineSHEOR);
                delete(app.LineSVEOR);
            end
            app.StartBT.Text = 'STOP';
            app.StartBT.BackgroundColor = 'red';
            app.SHEOL = [];
            app.SVEOL = [];
            app.SHEOR = [];
            app.SVEOR = [];

            % Crear líneas animadas
            app.LineSHEOL = animatedline(app.HEOL,  'Color', 'r');
            app.LineSVEOL = animatedline(app.VEOL,  'Color', 'g');
            app.LineSHEOR = animatedline(app.HEOR,  'Color', 'b');
            app.LineSVEOR = animatedline(app.VEOR,  'Color', 'black');

            app.counter           = 0;
            app.NoDataCount = 0;
            windowSize      = 50;

            % Crear timer que ejecuta el callback cada 20ms (50Hz)
            app.TimerGraficar = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period',         0.02, ...
                'TimerFcn',       @(~,~) GraficarDatos(app, windowSize), ...
                'ErrorFcn',       @(~,~) StopAcquisition(app, 'error'));

            start(app.TimerGraficar);
        end
        function GraficarDatos(app, windowSize)
            try
                if app.Device.NumBytesAvailable > 0
                    app.NoDataCount = 0;  % reset contador si hay datos
                    data   = readline(app.Device);
                    valores = str2double(split(strtrim(data), ","));

                    if length(valores) == 4
                        updateFS(app);
                        app.FSLabel.Text = sprintf('FS: %.1f Hz', app.fs_est);
                        s1 = valores(1);  s2 = valores(2);
                        s3 = valores(3);  s4 = valores(4);

                        S1mV = s1 * 0.80586080586;
                        S2mV = s2 * 0.80586080586;
                        S3mV = s3 * 0.80586080586;
                        S4mV = s4 * 0.80586080586;

                        if app.fs_calibrating
                            S1f = S1mV;
                            S2f = S2mV;
                            S3f = S3mV;
                            S4f = S4mV;
                        else
                            S1f = eogRealtimeFilter(app,S1mV, app.fs_fixed,1);
                            S2f = eogRealtimeFilter(app,S2mV, app.fs_fixed,2);
                            S3f = eogRealtimeFilter(app,S3mV, app.fs_fixed,3);
                            S4f = eogRealtimeFilter(app,S4mV, app.fs_fixed,4);
                        end

                        app.SHEOL(end+1) = S1mV;
                        app.SVEOL(end+1) = S2mV;
                        app.SHEOR(end+1) = S3mV;
                        app.SVEOR(end+1) = S4mV;

                        app.counter = app.counter + 1;

                        addpoints(app.LineSHEOL, app.counter, S1f)
                        addpoints(app.LineSVEOL, app.counter, S2f)
                        addpoints(app.LineSHEOR, app.counter, S3f)
                        addpoints(app.LineSVEOR, app.counter, S4f)

                        if app.counter > windowSize
                            app.HEOL.XLim = [app.counter - windowSize, app.counter];
                            app.VEOL.XLim = [app.counter - windowSize, app.counter];
                            app.HEOR.XLim = [app.counter - windowSize, app.counter];
                            app.VEOR.XLim = [app.counter - windowSize, app.counter];
                        end

                        drawnow limitrate
                    end

                else
                    % Sin datos: incrementar contador
                    app.NoDataCount = app.NoDataCount + 1;

                    % Si llevamos ~2 segundos sin datos (2s / 0.02s = 100 ticks)
                    if app.NoDataCount > 100
                        StopAcquisition(app, 'timeout');
                    end
                end

            catch
                StopAcquisition(app, 'error');
            end
        end

        function StopAcquisition(app, motivo)
            % Parar y eliminar el timer
            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
                app.TimerGraficar = [];
            end

            % Restaurar botón
            app.StartBT.Text            = 'START';
            app.StartBT.BackgroundColor = 'green';

            % Mensaje según el motivo
            switch motivo
                case 'timeout'
                    uialert(app.UIFigure, 'El dispositivo dejó de enviar datos.', ...
                        'Conexión perdida', 'Icon', 'warning');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];
                case 'manual'
                    % El usuario pulsó STOP, no mostrar alerta

                case 'error'
                    uialert(app.UIFigure, 'Error leyendo datos del dispositivo.', ...
                        'Error', 'Icon', 'error');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];
            end
        end

        function TomarMedidasClicked(app)
            ChangeWindowTo(app,app.GraficasScreen);
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                DataConnectionCable(app);
                disp(app.Device);
            else
                DataConnection(app);
                disp(app.Device);
            end
        end

        function AccederClicked(app)
            app.usuario = app.UserNameHTML.Data.value;
            app.password = app.PasswordHTML.Data.value;
            disp(app.usuario);
            disp(app.password);
            app.UserNameHTML.Data = struct('clear', true);
            app.PasswordHTML.Data = struct('clear', true);
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        function ConectarGafasClicked(app)
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                TomarMedidasClicked(app);
            else
                try
                    PrepareWifi(app);
                catch ME
                    uialert(app.UIFigure, ME.message, "Error WiFi");
                end
                ChangeWindowTo(app,app.ConectScreen);
                RefreshWifis(app);
            end
        end

        function VerAnalisisPreviosClicked(app)
            app.VerAnalisisPreviosHTML;
        end

        function AnalizarMedidasClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end

        function ResultadosAnalisisClicked(app)

            ChangeWindowTo(app,app.AnalisisScreen);
        end

        function BackConectClicked(app)
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        function BackChooseActionClicked(app)
            ChangeWindowTo(app,app.MainScreen);
        end

        function BackGraficasClicked(app)
            selection = uiconfirm(app.UIFigure, ...
                'Se va a desconectar del dispositivo actual ¿Quiere seguir con la operación?', ...
                'Confirmar Desconexión', ...
                'Options',       {'OK', 'Cancel'}, ...
                'DefaultOption', 'OK', ...
                'CancelOption',  'Cancel', ...
                'Icon',          'warning');
            switch selection
                case 'OK'
                    ChangeWindowTo(app,app.ChooseActionScreen);
                case 'Cancel'
                    return
            end
        end

        function PlotSignalsAcquired(app)

        end

        function y = eogRealtimeFilter(app, x, fs, ch)

            key = sprintf('ch%d', ch);

            % Inicializar canal si no existe o está vacío
            if isempty(app.FilterState) || ~isfield(app.FilterState, key)
                [b, a] = butter(2, 10/(fs/2), 'low');
                z = zeros(max(length(a), length(b)) - 1, 1);
                buffer = zeros(round(fs * 2), 1);
                smoothBuffer = zeros(5, 1);

                app.FilterState.(key) = struct( ...
                    'b', b, 'a', a, 'z', z, ...
                    'buffer', buffer, 'idx', 1, ...
                    'smoothBuffer', smoothBuffer);
            end

            s = app.FilterState.(key);

            % Baseline
            s.buffer(s.idx) = x;
            baseline = mean(s.buffer);
            x_hp = x - baseline;

            % Filtro paso bajo
            [x_lp, s.z] = filter(s.b, s.a, x_hp, s.z);

            % Suavizado
            s.smoothBuffer = [s.smoothBuffer(2:end); x_lp];
            y = mean(s.smoothBuffer);

            % Avanzar índice circular
            s.idx = s.idx + 1;
            if s.idx > length(s.buffer)
                s.idx = 1;
            end

            app.FilterState.(key) = s;
        end
        function updateFS(app)

            % Inicialización
            if isempty(app.lastTime)
                app.lastTime = tic;
                app.dtBuffer = [];
                app.fs_est = 0;
                app.fs_startTime = tic; % 🔥 inicio calibración
                return;
            end

            % Tiempo entre muestras
            dt = toc(app.lastTime);
            app.lastTime = tic;

            % Guardar dt
            app.dtBuffer(end+1) = dt;
            if length(app.dtBuffer) > 200
                app.dtBuffer(1) = [];
            end

            % Calcular fs estimado
            dt_clean = app.dtBuffer(app.dtBuffer < prctile(app.dtBuffer,95));

            if ~isempty(dt_clean)
                app.fs_est = 1 / mean(dt_clean);
            end

            % 🔥 CALIBRACIÓN DURANTE 1 SEGUNDO
            if app.fs_calibrating
                if toc(app.fs_startTime) > 1   % 1 segundo

                    % 🔥 REDONDEAR AQUÍ
                    app.fs_fixed = round(app.fs_est);

                    app.fs_calibrating = false;

                    fprintf('FS FIJADO: %.2f Hz\n', app.fs_fixed);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AccederHTML.Visible = 'off';
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;

            app.BackChooseActionHTML.HTMLSource = "GoBack.html";
            app.BackChooseActionHTML.Data = struct('id', 'ChooseActionScreen');
            app.BackConectHTML.HTMLSource = "GoBack.html";
            app.BackConectHTML.Data = struct('id', 'ConectScreen');
            app.BackGraficasHTML.HTMLSource = "GoBack.html";
            app.BackGraficasHTML.Data = struct('id', 'GraficasScreen');

            app.PasswordHTML.HTMLSource = 'TextField.html';
            app.PasswordHTML.Data = struct('type', 'password', 'label', 'Contraseña');
            app.UserNameHTML.HTMLSource = 'TextField.html';
            app.UserNameHTML.Data = struct('type', 'text', 'label', 'Usuario');

            app.WifiStatusHTML.HTMLSource= 'wifi_status.html';
            app.DevicesListHTML.HTMLSource = 'device_list.html';
            app.LogoHorusHTML.HTMLSource = 'logo_horus.html';
            app.LogoHorusHTML.Data = struct('color', '#808080');
            app.BuscarDevicesHTML.HTMLSource = 'buscar_dispositivo_button.html';

            buttons = [...
                app.TomarMedidasHTML,...
                app.AccederHTML,...
                app.ConectarGafasHTML,...
                app.VerAnalisisPreviosHTML,...
                app.AnalizarMedidasHTML,...
                app.ResultadosAnalisisHTML,...
                app.VerticalLExpandedHTML,...
                app.VerticalRExpandedHTML,...
                app.HorizontalLExpandedHTML,...
                app.HorizontalRExpandedHTML
                ];
            for b = buttons
                b.HTMLSource = 'Button.html';
            end
            app.AccederHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ecffff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '26px',    ...
                'label',        'Acceder' ...
                );
            app.AnalizarMedidasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'Analizar Medidas' ...
                );
            app.VerticalRExpandedHTML.Data = struct( ...
                'bgColor',      '#ffa0a0', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ffa0a0', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Vertical +' ...
                );
            app.HorizontalRExpandedHTML.Data = struct( ...
                'bgColor',      '#ffa0a0', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ffa0a0', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Horizontal +' ...
                );
            app.HorizontalLExpandedHTML.Data = struct( ...
                'bgColor',      '#9bbeff', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#9bbeff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Horizontal +' ...
                );
            app.VerticalLExpandedHTML.Data = struct( ...
                'bgColor',      '#9bbeff', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#9bbeff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Vertical +' ...
                );
            app.ResultadosAnalisisHTML.Data = struct( ...
                'bgColor',      '#e3ffe7', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#bb99ff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '20px',    ...
                'label',        'Resultados Analisis' ...
                );
            app.TomarMedidasHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#6a06b3', ...
                'borderColor',  '#9370db', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '18px',    ...
                'label',        'Tomar Medidas' ...
                );
            app.ConectarGafasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Conectar Gafas Horus' ...
                );
            app.VerAnalisisPreviosHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Ver Analisis Previos' ...
                );
            ChangeWindowTo(app,app.MainScreen);
            app.AccederHTML.Visible = 'on';
        end

        % Value changed function: ShowHiddenWifisCheckBox
        function ShowHiddenWifisClicked(app, event)
            value = app.ShowHiddenWifisCheckBox.Value;
            if value == 1
                ReloadListWifis(app,app.AvailableWifis);
            else
                ReloadListWifis(app,app.DevicesWifis);
            end
        end

        % Button pushed function: StartBT
        function StartClicked(app, event)
            if strcmp(app.StartBT.Text, 'START')
                TomarMedidasClicked(app);
                DataAcquisition(app);
            else
                StopAcquisition(app, 'manual');
            end
        end

        % Event received function: DevicesListHTML
        function DevicesListClicked(app, event)
            eventData = event.HTMLEventData;
            value = eventData.name;
            ChangeWifiStatus(app,'conectando');
            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connecting');
            ConectingWifi = uiprogressdlg(app.UIFigure, ...
                'Title', 'Conectando...', 'Message', 'Conectandose al Dispositivo', 'Indeterminate', 'on');
            drawnow
            for p = app.PasswordOptions
                ConectingWifi.Message = 'Probando Contraseña: ' + p;
                createWifiProfile(app,value,p);

                system("netsh wlan connect name=""" + value + """");

                timeout = 8;
                t0 = tic;

                while toc(t0) < timeout

                    [~,out] = system('netsh wlan show interfaces');

                    % comprobar estado
                    stateLine = regexp(out,'Estado\s*:\s*([^\r\n]*)','tokens','once');

                    % comprobar SSID
                    ssidLine = regexp(out,'SSID\s*:\s*([^\r\n]*)','tokens','once');

                    if ~isempty(stateLine) && ~isempty(ssidLine)

                        state = strtrim(lower(stateLine{1}));
                        currentSSID = strtrim(ssidLine{1});
                        disp("Estado: " + state)
                        disp("SSID: " + currentSSID)

                        if (strcmp(state,"conectado") || strcmp(state,"connected")) && strcmp(currentSSID,value)

                            disp("Conectado con: " + p);
                            app.ConnectedDevice = true;
                            close(ConectingWifi);
                            uialert(app.UIFigure,'Dispositivo Conectado','Conexion Hecha','Icon','success');
                            app.TomarMedidasHTML.Visible = 'on';
                            ChangeWifiStatus(app,'conectado');
                            app.LogoHorusHTML.Data = struct('color', '#95F18B');
                            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connected');
                            return

                        end

                    end

                    pause(0.3)

                end
                ChangeWifiStatus(app,'error');
                app.LogoHorusHTML.Data = struct('color', '#FF3333');
            end

        end

        % Event received function: BuscarDevicesHTML
        function BuscarDevicesClicked(app, event)
            if strcmp(event.HTMLEventName, "ButtonClicked")
                RefreshWifis(app);
            end
        end

        % Event received function: AccederHTML, AnalizarMedidasHTML, 
        % ...and 4 other components
        function ButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'ButtonClicked')
                if strcmp(eventData, 'Tomar Medidas')
                    TomarMedidasClicked(app);
                elseif strcmp(eventData, 'Acceder')
                    AccederClicked(app);
                elseif strcmp(eventData, 'Analizar Medidas')
                    AnalizarMedidasClicked(app);
                elseif strcmp(eventData, 'Resultados Analisis')
                    ResultadosAnalisisClicked(app);
                elseif strcmp(eventData, 'Conectar Gafas Horus')
                    ConectarGafasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                end
            end
        end

        % Event received function: BackChooseActionHTML, BackConectHTML, 
        % ...and 1 other component
        function BackButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData.id;
            disp(eventData);
            if strcmp(eventName, 'buttonClicked')
                if strcmp(eventData, 'ChooseActionScreen')
                    BackChooseActionClicked(app);
                elseif strcmp(eventData, 'ConectScreen')
                    BackConectClicked(app);
                elseif strcmp(eventData, 'GraficasScreen')
                    BackGraficasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EventsAnalisisScreen = uipanel(app.UIFigure);
            app.EventsAnalisisGrid = uigridlayout(app.EventsAnalisisScreen);
            app.GoBackButtonEvents = uibutton(app.EventsAnalisisGrid, 'push');
            app.GoBackButtonEvents.Text = 'Go Back';
            app.EventsAnalisisLBL = uilabel(app.EventsAnalisisGrid);
            app.EventsAnalisisLBL.Text = 'Analisis de Eventos Oculares';
            app.TotalEventsLBL = uilabel(app.EventsAnalisisGrid);
            app.TotalEventsLBL.Text = 'Todos los Eventos';
            app.EventsTable = uitable(app.EventsAnalisisGrid);
            app.EventsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EventsTable.RowName = {};
            app.ChooseActionScreen = uipanel(app.UIFigure);
            app.ChooseActionGrid = uigridlayout(app.ChooseActionScreen);
            app.Image = uiimage(app.ChooseActionGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GAFAS-HORUS-LOGO.svg');
            app.Image2 = uiimage(app.ChooseActionGrid);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'patient.svg');
            app.VerAnalisisPreviosHTML = uihtml(app.ChooseActionGrid);
            app.VerAnalisisPreviosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectarGafasHTML = uihtml(app.ChooseActionGrid);
            app.ConectarGafasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackChooseActionHTML = uihtml(app.ChooseActionGrid);
            app.BackChooseActionHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.ConectScreen = uipanel(app.UIFigure);
            app.ConectGrid = uigridlayout(app.ConectScreen);
            app.ShowHiddenWifisCheckBox = uicheckbox(app.ConectGrid);
            app.ShowHiddenWifisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHiddenWifisClicked, true);
            app.ShowHiddenWifisCheckBox.Text = 'Enseñar Redes Escondidas';
            app.WifiStatusHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @DevicesListClicked, true);
            app.LogoHorusHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BuscarDevicesClicked, true);
            app.TomarMedidasHTML = uihtml(app.ConectGrid);
            app.TomarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackConectHTML = uihtml(app.ConectGrid);
            app.BackConectHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.MainScreen = uipanel(app.UIFigure);
            app.MainGrid = uigridlayout(app.MainScreen);
            app.SignInLBL = uilabel(app.MainGrid);
            app.SignInLBL.Text = 'Inicia Sesión';
            app.LOGOSEPA = uiimage(app.MainGrid);
            app.LOGOSEPA.ImageSource = fullfile(pathToMLAPP, 'LOGO-SEPA-EXPANDED.png');
            app.SignInStateLBL = uilabel(app.MainGrid);
            app.SignInStateLBL.Text = 'Sesion Iniciada Correctamente';
            app.Hyperlink = uihyperlink(app.MainGrid);
            app.Hyperlink.Text = 'Crear Cuenta';
            app.AccederHTML = uihtml(app.MainGrid);
            app.AccederHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectionCableCheckBox = uicheckbox(app.MainGrid);
            app.ConectionCableCheckBox.Text = 'Conexión por cable';
            app.UserNameHTML = uihtml(app.MainGrid);
            app.PasswordHTML = uihtml(app.MainGrid);
            app.AnalisisScreen = uipanel(app.UIFigure);
            app.AnalisisGrid = uigridlayout(app.AnalisisScreen);
            app.GoBackButtonAnalisis = uibutton(app.AnalisisGrid, 'push');
            app.GoBackButtonAnalisis.Text = 'Go Back';
            app.UITableResultadosIzquierda = uitable(app.AnalisisGrid);
            app.UITableResultadosIzquierda.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosIzquierda.RowName = {};
            app.ANLISISPACIENTELabel = uilabel(app.AnalisisGrid);
            app.ANLISISPACIENTELabel.Text = 'ANÁLISIS PACIENTE';
            app.Image_2 = uiimage(app.AnalisisGrid);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PERSON-ICON.png');
            app.DATOSDELPACIENTELabel = uilabel(app.AnalisisGrid);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.FATIGAname = uilabel(app.AnalisisGrid);
            app.FATIGAname.Text = 'FATIGA:';
            app.PatientInfoLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel.Text = 'Label2';
            app.UITableResultadosDerecha = uitable(app.AnalisisGrid);
            app.UITableResultadosDerecha.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosDerecha.RowName = {};
            app.miradasIzquierdaLbl = uilabel(app.AnalisisGrid);
            app.miradasIzquierdaLbl.Text = 'Izq';
            app.miradasDerechaLbl = uilabel(app.AnalisisGrid);
            app.miradasDerechaLbl.Text = 'Der';
            app.ExportButton = uibutton(app.AnalisisGrid, 'push');
            app.ExportButton.Text = 'Export to .tsv';
            app.AnalisisTypeDropDown = uidropdown(app.AnalisisGrid);
            app.AnalisisTypeDropDown.Items = {'All', 'Tipo 1', 'Tipo 2'};
            app.AnalisisTypeDropDown.Value = 'All';
            app.TypeAnalisisLabel = uilabel(app.AnalisisGrid);
            app.TypeAnalisisLabel.Text = 'Total Analisis';
            app.EventsAnalisisButton = uibutton(app.AnalisisGrid, 'push');
            app.EventsAnalisisButton.Text = 'Events Analisis';
            app.ObservacionesNamelbl = uilabel(app.AnalisisGrid);
            app.ObservacionesNamelbl.Text = 'OBSERVACIONES:';
            app.ObservacionesLBL = uilabel(app.AnalisisGrid);
            app.ObservacionesLBL.Text = 'Label2';
            app.ExpandedViewScreen = uipanel(app.UIFigure);
            app.ExpandedViewGrid = uigridlayout(app.ExpandedViewScreen);
            app.ExpandedPlot = uiaxes(app.ExpandedViewGrid);
            title(app.ExpandedPlot, 'Signal View')
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            colormap(app.ExpandedPlot, 'winter')
            app.EyePanelL = uipanel(app.ExpandedViewGrid);
            app.EYEL_2 = uiimage(app.EyePanelL);
            app.EYEL_2.ImageSource = fullfile(pathToMLAPP, 'IRISL.png');
            app.PlayButton = uibutton(app.ExpandedViewGrid, 'push');
            app.PlayButton.Text = 'Play';
            app.EyePanelR = uipanel(app.ExpandedViewGrid);
            app.EYER_2 = uiimage(app.EyePanelR);
            app.EYER_2.ImageSource = fullfile(pathToMLAPP, 'IRISR.png');
            app.BackExpandedViewHTML = uihtml(app.ExpandedViewGrid);
            app.AnalisisSignalsScreen = uipanel(app.UIFigure);
            app.AnalisisSignalsGrid = uigridlayout(app.AnalisisSignalsScreen);
            app.LVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LVerticalAxis, 'L Vertical')
            zlabel(app.LVerticalAxis, 'Z')
            app.LHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LHorizontalAxis, 'L Horizontal')
            zlabel(app.LHorizontalAxis, 'Z')
            app.RVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RVerticalAxis, 'R Vertical')
            zlabel(app.RVerticalAxis, 'Z')
            app.RHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RHorizontalAxis, 'R Horizontal')
            zlabel(app.RHorizontalAxis, 'Z')
            app.EYER2 = uiimage(app.AnalisisSignalsGrid);
            app.EYER2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL2 = uiimage(app.AnalisisSignalsGrid);
            app.EYEL2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.HorizontalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.ResultadosAnalisisHTML = uihtml(app.AnalisisSignalsGrid);
            app.ResultadosAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.GraficasScreen = uipanel(app.UIFigure);
            app.GraficasGrid = uigridlayout(app.GraficasScreen);
            app.VEOR = uiaxes(app.GraficasGrid);
            title(app.VEOR, 'VEOR')
            zlabel(app.VEOR, 'Z')
            app.HEOL = uiaxes(app.GraficasGrid);
            title(app.HEOL, 'HEOL')
            zlabel(app.HEOL, 'Z')
            app.HEOR = uiaxes(app.GraficasGrid);
            title(app.HEOR, 'HEOR')
            zlabel(app.HEOR, 'Z')
            app.VEOL = uiaxes(app.GraficasGrid);
            title(app.VEOL, 'VEOL')
            zlabel(app.VEOL, 'Z')
            app.ConectedLampGrid = uigridlayout(app.GraficasGrid);
            app.ConectedLBL = uilabel(app.ConectedLampGrid);
            app.ConectedLBL.Text = 'Conectado';
            app.ConectedLamp = uilamp(app.ConectedLampGrid);
            app.StartBT = uibutton(app.GraficasGrid, 'push');
            app.StartBT.ButtonPushedFcn = createCallbackFcn(app, @StartClicked, true);
            app.StartBT.Icon = 'play.svg';
            app.StartBT.Text = 'START';
            app.EYER = uiimage(app.GraficasGrid);
            app.EYER.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL = uiimage(app.GraficasGrid);
            app.EYEL.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.AnalizarMedidasHTML = uihtml(app.GraficasGrid);
            app.AnalizarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackGraficasHTML = uihtml(app.GraficasGrid);
            app.BackGraficasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.FSLabel = uilabel(app.GraficasGrid);
            app.FSLabel.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HORUS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end